classdef Principal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DistflowPrincipal     matlab.ui.Figure             % Simulador de d...
        PanelCarga            matlab.ui.container.Panel    % Datos de simul...
        TabGroupCarga         matlab.ui.container.TabGroup % General, Sw, T...
        TabGeneral            matlab.ui.container.Tab      % General
        LabelEditField        matlab.ui.control.Label      % Red
        EditFieldRed          matlab.ui.control.EditField 
        LabelEditField2       matlab.ui.control.Label      % Consumos
        EditFieldConsumos     matlab.ui.control.EditField 
        LabelEditField3       matlab.ui.control.Label      % Utilidad Consumos
        EditFieldBetaT        matlab.ui.control.EditField 
        LabelEditField4       matlab.ui.control.Label      % Temperaturas
        EditFieldTemp         matlab.ui.control.EditField 
        LabelEditField5       matlab.ui.control.Label      % Costos Voltaje
        EditFieldCostosV      matlab.ui.control.EditField 
        LabelEditField6       matlab.ui.control.Label      % Costos Trasmision
        EditFieldCostosTras   matlab.ui.control.EditField 
        TabSwitchCapTap       matlab.ui.container.Tab      % Sw, Traf, Cap
        PanelSwitches         matlab.ui.container.Panel    % Switches
        CheckBoxAllSwitches   matlab.ui.control.CheckBox   % Todos los swit...
        LabelEditField7       matlab.ui.control.Label      % Desde
        EditFieldSwI          matlab.ui.control.EditField  % []
        LabelEditField8       matlab.ui.control.Label      % Hacia
        EditFieldSwJ          matlab.ui.control.EditField  % []
        LabelNumericEditField matlab.ui.control.Label      % Costos de switch
        NumericEditFieldCostosSwitches matlab.ui.control.NumericEditField
        PanelTrafos           matlab.ui.container.Panel    % Transformadores
        LabelListBox          matlab.ui.control.Label      % Activos
        ListBoxTrafos         matlab.ui.control.ListBox   
        ButtonAddTrafo        matlab.ui.control.Button     % Agregar Transf...
        ButtonEditTrafo       matlab.ui.control.Button     % Editar Transfo...
        ButtonDeleteTrafo     matlab.ui.control.Button     % Eliminar Trans...
        PanelCaps             matlab.ui.container.Panel    % Capacitores
        ButtonAddCap          matlab.ui.control.Button     % Agregar Capacitor
        ButtonEditCap         matlab.ui.control.Button     % Editar Capacitor
        Label                 matlab.ui.control.Label      % Activos
        ListBoxCaps           matlab.ui.control.ListBox   
        ButtonDeleteCap       matlab.ui.control.Button     % Eliminar Capac...
        TabGeneradores        matlab.ui.container.Tab      % Generadores
        PanelEolicos          matlab.ui.container.Panel    % Eolicos
        LabelListBox2         matlab.ui.control.Label      % Activos
        ListBoxEolicos        matlab.ui.control.ListBox   
        ButtonAddEol          matlab.ui.control.Button     % Agregar Gen. E...
        ButtonEditEol         matlab.ui.control.Button     % Editar Gen. Eo...
        ButtonDeleteEol       matlab.ui.control.Button     % Eliminar Gen. ...
        PanelSolares          matlab.ui.container.Panel    % Solares
        ButtonAddSol          matlab.ui.control.Button     % Agregar Gen. S...
        ButtonEditSol         matlab.ui.control.Button     % Editar Gen. Solar
        ButtonDeleteSol       matlab.ui.control.Button     % Eliminar Gen. ...
        Label2                matlab.ui.control.Label      % Activos
        ListBoxSolares        matlab.ui.control.ListBox   
        TabBaterias           matlab.ui.container.Tab      % Almacenamiento
        PanelBaterias         matlab.ui.container.Panel    % Baterias
        Label4                matlab.ui.control.Label      % Activos
        ListBoxBaterias       matlab.ui.control.ListBox   
        ButtonAddBat          matlab.ui.control.Button     % Agregar Bateria
        ButtonEditBat         matlab.ui.control.Button     % Editar Bateria
        ButtonDeleteBat       matlab.ui.control.Button     % Eliminar Bateria
        PanelAC               matlab.ui.container.Panel    % Aires Acondici...
        Label5                matlab.ui.control.Label      % Activos
        ListBoxAC             matlab.ui.control.ListBox   
        ButtonAddAC           matlab.ui.control.Button     % Agregar AC's
        ButtonEditAC          matlab.ui.control.Button     % Editar AC's
        ButtonDeleteAC        matlab.ui.control.Button     % Eliminar AC's
        TabCargasNoInt        matlab.ui.container.Tab      % Cargas No Int
        Tab                   matlab.ui.container.Tab      % Configuracion Sim
        ButtonCargaDatos      matlab.ui.control.Button     % Cargar Datos
        ButtonVisualizar      matlab.ui.control.Button     % Visualizar Datos
        ButtonEjecutar        matlab.ui.control.Button     % Ejecutar simul...
        ButtonGuardarInst     matlab.ui.control.Button     % Guardar Instancia
        ButtonCargarInst      matlab.ui.control.Button     % Cargar Instancia
    end


    properties (Access = public)
        DflowD % Description
        Config
        Data
    end

    methods (Access = public)
        
        function updateTrafos(app,Tr)
            app.DflowD.Trafos = [app.DflowD.Trafos Tr];
            app.ListBoxTrafos.Items = [app.ListBoxTrafos.Items ['N - ' num2str(Tr.nod) ...
                ' P - ' num2str(Tr.TP) ' [' num2str(min(Tr.N)) ',' num2str(max(Tr.N)) ']']];
            app.ListBoxTrafos.ItemsData = [app.ListBoxTrafos.ItemsData length(app.DflowD.Trafos)];
            
        end

        function updateEol(app,Eol)
            app.DflowD.Eolicos = [app.DflowD.Eolicos Eol];
            app.ListBoxEolicos.Items = [app.ListBoxEolicos.Items ['N - ' num2str(Eol.nod) ...
                ' G - ' num2str(Eol.type)]];
            app.ListBoxEolicos.ItemsData = [app.ListBoxEolicos.ItemsData length(app.DflowD.Eolicos)];
            
        end

        function updateSol(app,Sol)
            app.DflowD.Solares = [app.DflowD.Solares Sol];
            app.ListBoxSolares.Items = [app.ListBoxSolares.Items ['N - ' num2str(Sol.nod) ...
                ' G - ' num2str(Sol.type)]];
            app.ListBoxSolares.ItemsData = [app.ListBoxSolares.ItemsData length(app.DflowD.Solares)];
            
        end

        function updateCaps(app,Cap)
            app.DflowD.Caps = [app.DflowD.Caps Cap];
            app.ListBoxCaps.Items = [app.ListBoxCaps.Items ['N - ' num2str(Cap.nod)...
                ' P - ' num2str(Cap.TP) ' [' num2str(min(Cap.N)) ',' num2str(max(Cap.N)) ']']];
            app.ListBoxCaps.ItemsData = [app.ListBoxCaps.ItemsData length(app.DflowD.Caps)];
            
        end
        
        function updateBat(app,Bat)
            app.DflowD.Baterias = [app.DflowD.Baterias Bat];
            app.ListBoxBaterias.Items = [app.ListBoxBaterias.Items ['N - ' num2str(Bat.nod) ...
                ' B - ' num2str(Bat.type)]];
            app.ListBoxBaterias.ItemsData = [app.ListBoxBaterias.ItemsData length(app.DflowD.Baterias)];
        end

        function updateAC(app,AiCon)
            app.DflowD.ACs = [app.DflowD.ACs AiCon];
            app.ListBoxAC.Items = [app.ListBoxAC.Items AiCon.fileT];
            app.ListBoxAC.ItemsData = [app.ListBoxAC.ItemsData length(app.DflowD.ACs)];
        end

    end

    methods (Access = private)
        
        function cargarRed(app,DflowData)
            app.ButtonVisualizar.Enable = 'off';
            app.DflowD.inFilename = DflowData.inFilename;
            app.DflowD.fileCurvaCarga = DflowData.fileCurvaCarga;
            app.DflowD.fileUtilBetaT = DflowData.fileUtilBetaT;
            app.DflowD.fileTemp = DflowData.fileTemp;
            app.DflowD.fileCostosTension = DflowData.fileCostosTension;
            app.DflowD.fileCostosTras = DflowData.fileCostosTras;
            
            app.DflowD.Switches.i = DflowData.Switches.i;
            app.DflowD.Switches.j = DflowData.Switches.j;
            app.DflowD.Switches.cY = DflowData.Switches.cY;
            app.DflowD.Switches.all = DflowData.Switches.all;

            
            app.EditFieldRed.Value = app.DflowD.inFilename;
            app.EditFieldConsumos.Value = app.DflowD.fileCurvaCarga;
            app.EditFieldBetaT.Value = app.DflowD.fileUtilBetaT;
            app.EditFieldTemp.Value = app.DflowD.fileTemp;
            app.EditFieldCostosV.Value = app.DflowD.fileCostosTension;
            app.EditFieldCostosTras.Value = app.DflowD.fileCostosTras;
            app.CheckBoxAllSwitches.Value = app.DflowD.Switches.all;
            if ~app.DflowD.Switches.all
                app.EditFieldSwI.Value = mat2str(app.DflowD.Switches.i);
                app.EditFieldSwJ.Value = mat2str(app.DflowD.Switches.j);
            else
                app.EditFieldSwI.Value = '[]';
                app.EditFieldSwJ.Value = '[]';
            end
            app.NumericEditFieldCostosSwitches.Value = app.DflowD.Switches.cY;
            
        end
        
        function updatePrincipal(app,DflowData)
            for i = 1:length(DflowData.Trafos)
                updateTrafos(app,DflowData.Trafos(i));
            end
            for i = 1:length(DflowData.Eolicos)
                updateEol(app,DflowData.Eolicos(i));
            end
            for i = 1:length(DflowData.Solares)
                updateSol(app,DflowData.Solares(i));
            end
            for i = 1:length(DflowData.Caps)
                updateCaps(app,DflowData.Caps(i));
            end
            for i = 1:length(DflowData.Baterias)
                updateBat(app,DflowData.Baterias(i));
            end
            for i = 1:length(DflowData.ACs)
                updateAC(app,DflowData.ACs(i));
            end
            cargarRed(app,DflowData)
            
            [app.Data] = loadData(app.DflowD);
        end
        
        function [DflowData, DataD] = updateDflow(app)
            DflowData = app.DflowD;
            DflowData.inFilename = app.EditFieldRed.Value;
            DflowData.fileCurvaCarga = app.EditFieldConsumos.Value;
            DflowData.fileUtilBetaT = app.EditFieldBetaT.Value;
            DflowData.fileTemp = app.EditFieldTemp.Value;
            DflowData.fileCostosTension = app.EditFieldCostosV.Value;
            DflowData.fileCostosTras = app.EditFieldCostosTras.Value;
            
            DflowData.Switches.all = app.CheckBoxAllSwitches.Value;
            if ~DflowData.Switches.all
                eval(['DflowData.Switches.i = ',app.EditFieldSwI.Value,';']);
                eval(['DflowData.Switches.j = ',app.EditFieldSwJ.Value,';']);
            else
                DflowData.Switches.i = [];
                DflowData.Switches.j = [];
            end
            DflowData.Switches.cY = app.NumericEditFieldCostosSwitches.Value;
            
            [DataD] = loadData(DflowData);
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Inicializacion del DflowD
            app.DflowD = cargaDflowDDefault();
            updatePrincipal(app,app.DflowD);
            
            app.Config.iniEtapa = 1;
            app.Config.Etapas = 4;
            app.Config.outFilename = 'PU_example4';
            app.Config.runNxN = true;
            app.Config.runM = true;
            app.Config.Centr = [];

        end

        % ButtonCargaDatos button pushed function
        function ButtonCarga_ButtonPushed(app)
            app.ButtonVisualizar.Enable = 'off';
            [app.DflowD, app.Data] = updateDflow(app);
            app.ButtonVisualizar.Enable = 'on';
            
        end

        % ButtonVisualizar button pushed function
        function ButtonVisualizar_ButtonPushed(app)
            plotRed(app.Data);
        end

        % CheckBoxAllSwitches value changed function
        function CheckBoxAllSwitches_ValueChanged(app)
            editIJ = [];
            if app.CheckBoxAllSwitches.Value
                editIJ = 'off';
            else
                editIJ = 'on';
            end
            app.EditFieldSwI.Enable = editIJ;
            app.EditFieldSwJ.Enable = editIJ;
            
        end

        % ButtonEditTrafo button pushed function
        function ButtonEditTrafo_ButtonPushed(app)
            if ~isempty(app.ListBoxTrafos.Value)
                tr = Trafo;
                currTrafo = app.DflowD.Trafos(app.ListBoxTrafos.Value);
                tr.NumericEditFieldNtrLow.Value = min(currTrafo.N);
                tr.NumericEditFieldNtrTop.Value = max(currTrafo.N);
                tr.NumericEditFieldPaso.Value = currTrafo.TP;
                tr.NumericEditFieldNodo.Value = currTrafo.nod;
                tr.NumericEditFieldNtrIni.Value = currTrafo.ini;
                tr.NumericEditFieldCambio.Value = currTrafo.cambio;
                tr.Principal = app;
            end

        end

        % ButtonAddTrafo button pushed function
        function ButtonAddTrafo_ButtonPushed(app)
            t = Trafo;
            t.Principal = app;
           
        end

        % ButtonDeleteTrafo button pushed function
        function ButtonDeleteTrafo_ButtonPushed(app)
            [app.ListBoxTrafos,app.DflowD.Trafos] = deleteAgente(app.ListBoxTrafos,app.DflowD.Trafos);
        end

        % ButtonAddCap button pushed function
        function ButtonAddCap_ButtonPushed(app)
            c = Cap;
            c.Principal = app;
        end

        % ButtonEditCap button pushed function
        function ButtonEditCap_ButtonPushed(app)
            if ~isempty(app.ListBoxCaps.Value)
                c = Cap;
                currCap = app.DflowD.Caps(app.ListBoxCaps.Value);
                c.NumericEditFieldNcpLow.Value = min(currCap.N);
                c.NumericEditFieldNcpTop.Value = max(currCap.N);
                c.NumericEditFieldPaso.Value = currCap.TP;
                c.NumericEditFieldNodo.Value = currCap.nod;
                c.NumericEditFieldNtrIni.Value = currCap.ini;
                c.NumericEditFieldCambio.Value = currCap.cambio;
                c.Principal = app;
            end
            
        end

        % ButtonDeleteCap button pushed function
        function ButtonDeleteCap_ButtonPushed(app)
            [app.ListBoxCaps,app.DflowD.Caps] = deleteAgente(app.ListBoxCaps,app.DflowD.Caps);
        end

        % ButtonAddEol button pushed function
        function ButtonAddEol_ButtonPushed(app)
            e = Eolico;
            e.Principal = app;
            
        end

        % ButtonDeleteEol button pushed function
        function ButtonDeleteEol_ButtonPushed(app)
            [app.ListBoxEolicos,app.DflowD.Eolicos] = deleteAgente(app.ListBoxEolicos,app.DflowD.Eolicos);
        end

        % ButtonEditEol button pushed function
        function ButtonEditEol_ButtonPushed(app)
            if ~isempty(app.ListBoxEolicos.Value)
                e = Eolico;
                currEol = app.DflowD.Eolicos(app.ListBoxEolicos.Value);
				
                aux = currEol.type;
                aux = strrep(aux,'casos\gen\dfig\','');
                aux = strrep(aux,'.csv', '');
                
                e.DropDownType.Value = aux;
                e.EditFieldFileG.Value = currEol.fileG;
                e.EditFieldFileC.Value = currEol.fileC;
                e.NumericEditFieldNodo.Value = currEol.nod;
                e.Principal = app;
            end
        end

        % ButtonAddSol button pushed function
        function ButtonAddSol_ButtonPushed(app)
            s = Solar;
            s.Principal = app;

        end

        % ButtonDeleteSol button pushed function
        function ButtonDeleteSol_ButtonPushed(app)
            [app.ListBoxSolares,app.DflowD.Solares] = deleteAgente(app.ListBoxSolares,app.DflowD.Solares);
        end

        % ButtonEditSol button pushed function
        function ButtonEditSol_ButtonPushed(app)
            if ~isempty(app.ListBoxSolares.Value)
                s = Solar;
                currSol = app.DflowD.Solares(app.ListBoxSolares.Value);
				
                aux = currSol.type;
                aux = strrep(aux,'casos\gen\pv\','');
                aux = strrep(aux,'.csv', '');
                
                s.DropDownType.Value = aux;
                s.EditFieldFileG.Value = currSol.fileG;
                s.EditFieldFileC.Value = currSol.fileC;
                s.NumericEditFieldNodo.Value = currSol.nod;
                s.Principal = app;
            end

        end

        % ButtonAddBat button pushed function
        function ButtonAddBat_ButtonPushed(app)
            b = Bateria;
            b.Principal = app;
    
        end

        % ButtonEditBat button pushed function
        function ButtonEditBat_ButtonPushed(app)
            if ~isempty(app.ListBoxBaterias.Value)
                b = Bateria;
                currBat = app.DflowD.Baterias(app.ListBoxBaterias.Value);
				
                aux = currBat.type;
                aux = strrep(aux,'casos\gen\bat\','');
                aux = strrep(aux,'.csv', '');
                
                b.DropDownType.Value = aux;
                b.NumericEditFieldNodo.Value = currBat.nod;
                b.NumericEditFieldEIni.Value = currBat.EIni;
                b.Principal = app;
            end

        end

        % ButtonDeleteBat button pushed function
        function ButtonDeleteBat_ButtonPushed(app)
            [app.ListBoxBaterias,app.DflowD.Baterias] = deleteAgente(app.ListBoxBaterias,app.DflowD.Baterias);
        end

        % ButtonDeleteAC button pushed function
        function ButtonDeleteAC_ButtonPushed(app)
            [app.ListBoxAC,app.DflowD.ACs] = deleteAgente(app.ListBoxAC,app.DflowD.ACs);            
        end

        % ButtonAddAC button pushed function
        function ButtonAddAC_ButtonPushed(app)
            a = AC;
            a.Principal = app;
        end

        % ButtonEditAC button pushed function
        function ButtonEditAC_ButtonPushed(app)
            if ~isempty(app.ListBoxAC.Value)
                a = AC;
                currAC = app.DflowD.ACs(app.ListBoxAC.Value);
				
                a.EditFieldFileT.Value = currAC.fileT;
                a.NumericEditFieldTempIni.Value = currAC.tempIni;
                a.NumericEditFieldEpsilon.Value = currAC.epsilon;
                a.NumericEditFieldEta.Value = currAC.eta;
                a.Principal = app;
            end

        end

        % ButtonGuardarInst button pushed function
        function ButtonGuardarInst_ButtonPushed(app)
            [FileName,PathName] = uiputfile('*.mat','Guardar definicion de simulacion');
            [app.DflowD, app.Data] = updateDflow(app);
            DflowData = app.DflowD;
            ConfigData = app.Config;
            save([PathName '\' FileName],'DflowData', 'ConfigData');
        end

        % ButtonCargarInst button pushed function
        function ButtonCargarInst_ButtonPushed(app)
            [FileName,PathName,~] = uigetfile('*.mat','Cargar definicion de simulacion');
            aux = load([PathName '\' FileName], 'DflowData');
            if ~isempty(aux)
                updatePrincipal(app,aux.DflowData);
            else
                w = warndlg('Faltan datos para la carga de la simulacion');
            end

            aux = load([PathName '\' FileName], 'ConfigData');
            if ~isempty(aux)
                app.Config = aux.ConfigData;
            else
                w = warndlg('Faltan datos para la configuracion de la simulacion');
            end

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DistflowPrincipal = uifigure;
            app.DistflowPrincipal.Name = 'Simulador de distflow - ORT';
            setAutoResize(app, app.DistflowPrincipal, true)
            app.PanelCarga = uipanel(app.DistflowPrincipal);
            app.PanelCarga.Title = 'Datos de simulacion';
            app.PanelCarga.Units = 'pixels';
            app.TabGroupCarga = uitabgroup(app.PanelCarga);
            app.TabGroupCarga.Units = 'pixels';
            app.TabGeneral = uitab(app.TabGroupCarga);
            app.TabGeneral.Units = 'pixels';
            app.TabGeneral.Title = 'General';
            app.LabelEditField = uilabel(app.TabGeneral);
            app.LabelEditField.Text = 'Red';
            app.EditFieldRed = uieditfield(app.TabGeneral, 'text');
            app.LabelEditField2 = uilabel(app.TabGeneral);
            app.LabelEditField2.Text = 'Consumos';
            app.EditFieldConsumos = uieditfield(app.TabGeneral, 'text');
            app.LabelEditField3 = uilabel(app.TabGeneral);
            app.LabelEditField3.Text = 'Utilidad Consumos';
            app.EditFieldBetaT = uieditfield(app.TabGeneral, 'text');
            app.LabelEditField4 = uilabel(app.TabGeneral);
            app.LabelEditField4.Text = 'Temperaturas';
            app.EditFieldTemp = uieditfield(app.TabGeneral, 'text');
            app.LabelEditField5 = uilabel(app.TabGeneral);
            app.LabelEditField5.Text = 'Costos Voltaje';
            app.EditFieldCostosV = uieditfield(app.TabGeneral, 'text');
            app.LabelEditField6 = uilabel(app.TabGeneral);
            app.LabelEditField6.Text = 'Costos Trasmision';
            app.EditFieldCostosTras = uieditfield(app.TabGeneral, 'text');
            app.TabSwitchCapTap = uitab(app.TabGroupCarga);
            app.TabSwitchCapTap.Units = 'pixels';
            app.TabSwitchCapTap.Title = 'Sw, Traf, Cap';
            app.PanelSwitches = uipanel(app.TabSwitchCapTap);
            app.PanelSwitches.Title = 'Switches';
            app.PanelSwitches.Units = 'pixels';
            app.CheckBoxAllSwitches = uicheckbox(app.PanelSwitches);
            app.CheckBoxAllSwitches.ValueChangedFcn = createCallbackFcn(app, @CheckBoxAllSwitches_ValueChanged);
            app.CheckBoxAllSwitches.Text = 'Todos los switches decidibles';
            app.CheckBoxAllSwitches.Value = true;
            app.LabelEditField7 = uilabel(app.PanelSwitches);
            app.LabelEditField7.Enable = 'off';
            app.LabelEditField7.Text = 'Desde';
            app.EditFieldSwI = uieditfield(app.PanelSwitches, 'text');
            app.EditFieldSwI.Enable = 'off';
            app.EditFieldSwI.Value = '[]';
            app.LabelEditField8 = uilabel(app.PanelSwitches);
            app.LabelEditField8.Enable = 'off';
            app.LabelEditField8.Text = 'Hacia';
            app.EditFieldSwJ = uieditfield(app.PanelSwitches, 'text');
            app.EditFieldSwJ.Enable = 'off';
            app.EditFieldSwJ.Value = '[]';
            app.LabelNumericEditField = uilabel(app.PanelSwitches);
            app.LabelNumericEditField.Text = 'Costos de switch';
            app.NumericEditFieldCostosSwitches = uieditfield(app.PanelSwitches, 'numeric');
            app.PanelTrafos = uipanel(app.TabSwitchCapTap);
            app.PanelTrafos.Title = 'Transformadores';
            app.PanelTrafos.Units = 'pixels';
            app.LabelListBox = uilabel(app.PanelTrafos);
            app.LabelListBox.Text = 'Activos';
            app.ListBoxTrafos = uilistbox(app.PanelTrafos);
            app.ListBoxTrafos.Items = {};
            app.ListBoxTrafos.Value = {};
            app.ButtonAddTrafo = uibutton(app.PanelTrafos, 'push');
            app.ButtonAddTrafo.ButtonPushedFcn = createCallbackFcn(app, @ButtonAddTrafo_ButtonPushed);
            app.ButtonAddTrafo.Text = 'Agregar Transformador';
            app.ButtonEditTrafo = uibutton(app.PanelTrafos, 'push');
            app.ButtonEditTrafo.ButtonPushedFcn = createCallbackFcn(app, @ButtonEditTrafo_ButtonPushed);
            app.ButtonEditTrafo.Text = 'Editar Transformador';
            app.ButtonDeleteTrafo = uibutton(app.PanelTrafos, 'push');
            app.ButtonDeleteTrafo.ButtonPushedFcn = createCallbackFcn(app, @ButtonDeleteTrafo_ButtonPushed);
            app.ButtonDeleteTrafo.Text = 'Eliminar Transformador';
            app.PanelCaps = uipanel(app.TabSwitchCapTap);
            app.PanelCaps.Title = 'Capacitores';
            app.PanelCaps.Units = 'pixels';
            app.ButtonAddCap = uibutton(app.PanelCaps, 'push');
            app.ButtonAddCap.ButtonPushedFcn = createCallbackFcn(app, @ButtonAddCap_ButtonPushed);
            app.ButtonAddCap.Text = 'Agregar Capacitor';
            app.ButtonEditCap = uibutton(app.PanelCaps, 'push');
            app.ButtonEditCap.ButtonPushedFcn = createCallbackFcn(app, @ButtonEditCap_ButtonPushed);
            app.ButtonEditCap.Text = 'Editar Capacitor';
            app.Label = uilabel(app.PanelCaps);
            app.Label.Text = 'Activos';
            app.ListBoxCaps = uilistbox(app.PanelCaps);
            app.ListBoxCaps.Items = {};
            app.ListBoxCaps.Value = {};
            app.ButtonDeleteCap = uibutton(app.PanelCaps, 'push');
            app.ButtonDeleteCap.ButtonPushedFcn = createCallbackFcn(app, @ButtonDeleteCap_ButtonPushed);
            app.ButtonDeleteCap.Text = 'Eliminar Capacitor';
            app.TabGeneradores = uitab(app.TabGroupCarga);
            app.TabGeneradores.Units = 'pixels';
            app.TabGeneradores.Title = 'Generadores';
            app.PanelEolicos = uipanel(app.TabGeneradores);
            app.PanelEolicos.Title = 'Eolicos';
            app.PanelEolicos.Units = 'pixels';
            app.LabelListBox2 = uilabel(app.PanelEolicos);
            app.LabelListBox2.Text = 'Activos';
            app.ListBoxEolicos = uilistbox(app.PanelEolicos);
            app.ListBoxEolicos.Items = {};
            app.ListBoxEolicos.Value = {};
            app.ButtonAddEol = uibutton(app.PanelEolicos, 'push');
            app.ButtonAddEol.ButtonPushedFcn = createCallbackFcn(app, @ButtonAddEol_ButtonPushed);
            app.ButtonAddEol.Text = 'Agregar Gen. Eolico';
            app.ButtonEditEol = uibutton(app.PanelEolicos, 'push');
            app.ButtonEditEol.ButtonPushedFcn = createCallbackFcn(app, @ButtonEditEol_ButtonPushed);
            app.ButtonEditEol.Text = 'Editar Gen. Eolico';
            app.ButtonDeleteEol = uibutton(app.PanelEolicos, 'push');
            app.ButtonDeleteEol.ButtonPushedFcn = createCallbackFcn(app, @ButtonDeleteEol_ButtonPushed);
            app.ButtonDeleteEol.Text = 'Eliminar Gen. Eolico';
            app.PanelSolares = uipanel(app.TabGeneradores);
            app.PanelSolares.Title = 'Solares';
            app.PanelSolares.Units = 'pixels';
            app.ButtonAddSol = uibutton(app.PanelSolares, 'push');
            app.ButtonAddSol.ButtonPushedFcn = createCallbackFcn(app, @ButtonAddSol_ButtonPushed);
            app.ButtonAddSol.Text = 'Agregar Gen. Solar';
            app.ButtonEditSol = uibutton(app.PanelSolares, 'push');
            app.ButtonEditSol.ButtonPushedFcn = createCallbackFcn(app, @ButtonEditSol_ButtonPushed);
            app.ButtonEditSol.Text = 'Editar Gen. Solar';
            app.ButtonDeleteSol = uibutton(app.PanelSolares, 'push');
            app.ButtonDeleteSol.ButtonPushedFcn = createCallbackFcn(app, @ButtonDeleteSol_ButtonPushed);
            app.ButtonDeleteSol.Text = 'Eliminar Gen. Solar';
            app.Label2 = uilabel(app.PanelSolares);
            app.Label2.Text = 'Activos';
            app.ListBoxSolares = uilistbox(app.PanelSolares);
            app.ListBoxSolares.Items = {};
            app.ListBoxSolares.Value = {};
            app.TabBaterias = uitab(app.TabGroupCarga);
            app.TabBaterias.Units = 'pixels';
            app.TabBaterias.Title = 'Almacenamiento';
            app.PanelBaterias = uipanel(app.TabBaterias);
            app.PanelBaterias.Title = 'Baterias';
            app.PanelBaterias.Units = 'pixels';
            app.Label4 = uilabel(app.PanelBaterias);
            app.Label4.Text = 'Activos';
            app.ListBoxBaterias = uilistbox(app.PanelBaterias);
            app.ListBoxBaterias.Items = {};
            app.ListBoxBaterias.Value = {};
            app.ButtonAddBat = uibutton(app.PanelBaterias, 'push');
            app.ButtonAddBat.ButtonPushedFcn = createCallbackFcn(app, @ButtonAddBat_ButtonPushed);
            app.ButtonAddBat.Text = 'Agregar Bateria';
            app.ButtonEditBat = uibutton(app.PanelBaterias, 'push');
            app.ButtonEditBat.ButtonPushedFcn = createCallbackFcn(app, @ButtonEditBat_ButtonPushed);
            app.ButtonEditBat.Text = 'Editar Bateria';
            app.ButtonDeleteBat = uibutton(app.PanelBaterias, 'push');
            app.ButtonDeleteBat.ButtonPushedFcn = createCallbackFcn(app, @ButtonDeleteBat_ButtonPushed);
            app.ButtonDeleteBat.Text = 'Eliminar Bateria';
            app.PanelAC = uipanel(app.TabBaterias);
            app.PanelAC.Title = 'Aires Acondicionados';
            app.PanelAC.Units = 'pixels';
            app.Label5 = uilabel(app.PanelAC);
            app.Label5.Text = 'Activos';
            app.ListBoxAC = uilistbox(app.PanelAC);
            app.ListBoxAC.Items = {};
            app.ListBoxAC.Value = {};
            app.ButtonAddAC = uibutton(app.PanelAC, 'push');
            app.ButtonAddAC.ButtonPushedFcn = createCallbackFcn(app, @ButtonAddAC_ButtonPushed);
            app.ButtonAddAC.Text = 'Agregar AC''s';
            app.ButtonEditAC = uibutton(app.PanelAC, 'push');
            app.ButtonEditAC.ButtonPushedFcn = createCallbackFcn(app, @ButtonEditAC_ButtonPushed);
            app.ButtonEditAC.Text = 'Editar AC''s';
            app.ButtonDeleteAC = uibutton(app.PanelAC, 'push');
            app.ButtonDeleteAC.ButtonPushedFcn = createCallbackFcn(app, @ButtonDeleteAC_ButtonPushed);
            app.ButtonDeleteAC.Text = 'Eliminar AC''s';
            app.TabCargasNoInt = uitab(app.TabGroupCarga);
            app.TabCargasNoInt.Units = 'pixels';
            app.TabCargasNoInt.Title = 'Cargas No Int';
            app.Tab = uitab(app.TabGroupCarga);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Configuracion Sim';
            app.ButtonCargaDatos = uibutton(app.PanelCarga, 'push');
            app.ButtonCargaDatos.ButtonPushedFcn = createCallbackFcn(app, @ButtonCarga_ButtonPushed);
            app.ButtonCargaDatos.Text = 'Cargar Datos';
            app.ButtonVisualizar = uibutton(app.PanelCarga, 'push');
            app.ButtonVisualizar.ButtonPushedFcn = createCallbackFcn(app, @ButtonVisualizar_ButtonPushed);
            app.ButtonVisualizar.Enable = 'off';
            app.ButtonVisualizar.Text = 'Visualizar Datos';
            app.ButtonEjecutar = uibutton(app.PanelCarga, 'push');
            app.ButtonEjecutar.Enable = 'off';
            app.ButtonEjecutar.Text = 'Ejecutar simulacion';
            app.ButtonGuardarInst = uibutton(app.PanelCarga, 'push');
            app.ButtonGuardarInst.ButtonPushedFcn = createCallbackFcn(app, @ButtonGuardarInst_ButtonPushed);
            app.ButtonGuardarInst.Text = 'Guardar Instancia';
            app.ButtonCargarInst = uibutton(app.PanelCarga, 'push');
            app.ButtonCargarInst.ButtonPushedFcn = createCallbackFcn(app, @ButtonCargarInst_ButtonPushed);
            app.ButtonCargarInst.Text = 'Cargar Instancia';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Principal()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DistflowPrincipal)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DistflowPrincipal)
        end
    end
end